classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CommentsEditField          matlab.ui.control.EditField
        CommentsEditFieldLabel     matlab.ui.control.Label
        CalligraphyEditField       matlab.ui.control.EditField
        CalligraphyEditFieldLabel  matlab.ui.control.Label
        ScoreEditField             matlab.ui.control.NumericEditField
        ScoreEditFieldLabel        matlab.ui.control.Label
        AIAnalysisButton           matlab.ui.control.Button
        uploadimageButton          matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    UploadedImage % 用于存储上传的图片
    ImageLabel    % 可选：用于显示图片信息的标签 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: uploadimageButton
        function upload(app, event)
    % 打开文件选择对话框
    [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp', 'Image Files (*.png, *.jpg, *.jpeg, *.bmp)'}, ...
                              'Select an Image');
    
    % 如果用户取消了文件选择
    if isequal(file, 0)
        return; % 退出函数
    end

    % 构建文件完整路径
    filepath = fullfile(path, file);

    % 读取图片文件并保存到属性
    app.UploadedImage = imread(filepath);

    % 在 UIAxes 中显示图片
    imshow(app.UploadedImage, 'Parent', app.UIAxes);

    % 可选：更新界面提示
    if isprop(app, 'ImageLabel')
        [height, width, ~] = size(app.UploadedImage);
        app.ImageLabel.Text = sprintf('Image Uploaded: %d x %d', height, width);
    end
        end

        % Button down function: UIAxes
        function show(app, event)

    % 检查是否有上传的图片
    if ~isprop(app, 'UploadedImage') || isempty(app.UploadedImage)
        uialert(app.UIFigure, 'No image uploaded. Please upload an image first.', 'Error');
        return;
    end

    % 在 UIAxes 中重新显示图片（如果需要重复显示）
    imshow(app.UploadedImage, 'Parent', app.UIAxes);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @show, true);
            app.uploadimageButton = uibutton(app.UIFigure, 'push');
            app.uploadimageButton.ButtonPushedFcn = createCallbackFcn(app, @upload, true);
            app.uploadimageButton.Text = 'upload image';
            app.AIAnalysisButton = uibutton(app.UIFigure, 'push');
            app.AIAnalysisButton.Text = 'AI Analysis ';
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score:     ';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalligraphyEditFieldLabel = uilabel(app.UIFigure);
            app.CalligraphyEditFieldLabel.Text = 'Calligraphy:';
            app.CalligraphyEditField = uieditfield(app.UIFigure, 'text');
            app.CommentsEditFieldLabel = uilabel(app.UIFigure);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end